classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FossilUI             matlab.ui.Figure
        FileMenu             matlab.ui.container.Menu
        AboutMenu            matlab.ui.container.Menu
        ProgramInfoMenu      matlab.ui.container.Menu
        EditMenu             matlab.ui.container.Menu
        LeftPanel            matlab.ui.container.Panel
        EditorPane           matlab.ui.control.TextArea
        RightPanel           matlab.ui.container.Panel
        TabGroup             matlab.ui.container.TabGroup
        OutputTab            matlab.ui.container.Tab
        OutputPane           matlab.ui.control.HTML
        RUNButton            matlab.ui.control.Button
        ProgramNamef90Label  matlab.ui.control.Label
        ProgramName          matlab.ui.control.EditField
        SyntaxTab            matlab.ui.container.Tab
        SyntaxPane           matlab.ui.control.HTML
        CHECKSYNTAXButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        ver = '0.91'; % current version of the app
        release_date = 'June 1, 2020'; % release date of this version
        ss = get(groot, "ScreenSize"); % [x, y, width, height]
        
        % swaps between the two html files for syntax output, inefficient?
        i_index = 0; % see CHECKSYNTAXButtonPushed for more information
        
        output_history = "";
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %%%%%% ----- for UI info, see docs/LAYOUT.md ----- %%%%%%          
            cd ~/Documents/GitHub/Fossil
            
            
            %%%% --- INITIALIZE STARTUP POSITIONS --- %%%%
            % FOSSIL UI = full screen
            app.FossilUI.Position = app.ss; % = app.screen_size
            % set Fossil UI name to "Fossil v[version] - [/dir]"
            app.FossilUI.Name = ['Fossil v' app.ver ' - ' pwd];
            
            
            %% LEFT PANEL component positions %%
            % Editor pane on main stage, spare room above
            app.LeftPanel.Position = [1, 1, app.ss(3)/2, app.ss(4)-118];
            app.EditorPane.Position = [10, 10, ...
                app.ss(3)/2-20, app.ss(4)-170];
            
            
            %% RIGHT PANEL component positions %%
            % Tabs dimensions are same as panel minus a small amount to fit
            app.RightPanel.Position = [app.ss(3)/2, 1, ...
                app.ss(3)/2, app.ss(4)-118];
            app.TabGroup.Position = [10, 10, ...
                app.ss(3)/2-20, app.ss(4)-135];
            % TAB 1 - OUTPUT %
            app.OutputPane.Position = [20, 20, ...
                app.ss(3)/2-60, app.ss(4)-230];
            app.RUNButton.Position = [20, app.ss(4)-200, 80, 30];
            app.ProgramName.Position = [240, app.ss(4)-200, 120, 30];
            app.ProgramNamef90Label.Position = [110, app.ss(4)-200, ...
                120, 30];
            % TAB 2 - SYNTAX %
            app.SyntaxPane.Position = [20, 20, ...
                app.ss(3)/2-60, app.ss(4)-230];
            app.CHECKSYNTAXButton.Position = [20, app.ss(4)-200, 160, 30];
            
            
            %%%% --- INITIALIZE OBJECT COLORS & FONTS --- %%%%
            % set Fossil UI name to "Fossil v[version] - [/dir]"
            app.ProgramNamef90Label.FontSize = 12;
            app.EditorPane.FontSize = 13;
            app.EditorPane.FontName = 'Geneva';
            app.EditorPane.BackgroundColor = [0.94, 0.94, 0.94];
            
            
            %%%% --- SET DEFAULT PROGRAM: Hello World! --- %%%%
            % Set default as a simple Hello World Program
            HelloWorldStr = sprintf('%s\n%s\n%s\n', ...
                "PROGRAM hello", ...
                "    PRINT *,""Hello World!""", ...
                "END PROGRAM hello");
            set(app.EditorPane, 'Value', HelloWorldStr);
            set(app.ProgramName, 'Value', "hello")
            
            
            %%%% --- SHOW BASIC INSTRUCTIONS IN OUTPUT --- %%%%
            % Set default output pane to a set of instructions
            app.OutputPane.HTMLSource = fullfile(pwd, 'tools/def_output.html');
            app.SyntaxPane.HTMLSource = fullfile(pwd, 'tools/def_syntax.html');
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            
            %% --- Run compile command in terminal & showing output --- %%
            % Open f90 file and write code from editor pane to the file.
            %  The f90 file name is stored in 'file_name' with file ID =
            %  FID1.
            file_name = get(app.ProgramName, 'Value');
            fID1 = fopen(['test/' file_name '.f90'],'w');
            fprintf(fID1,'%s\n', app.EditorPane.Value{:});
            fclose(fID1); % close f90 file
            
            % Opens an html file and write output from command in terminal
            %  to a file. Uses alternating file names 'tools/output0.html'
            %  and 'tools/output1.html' in order to refresh the app output 
            %  html object. See app properties for description of the 
            %  problem. The html file name is stored in 'file_name_out'
            %  with file ID = fID2
            file_name_out = ['tools/output' num2str(app.i_index) '.html'];
            app.i_index = 1 - app.i_index;
            fID2 = fopen(file_name_out, 'w');
            
            % Pre, output, and post are declared first, then written to
            %  'file_name_out'
            pre = sprintf('%s\n%s\n  %s\n%s\n%s\n%s', ...
                "<html>", ...
                "<head>", ...
                "<link rel=""stylesheet"" href=""output.css""/>", ...
                "</head>", ...
                "<body>", ...
                "<pre>");
            
            % Run the terminal command, printing all good and bad outputs,
            %  flag is currently unused, build tab here?
            [~, output] = system(['cd ~/Documents/GitHub/Fossil/test; ', + ...
                '/usr/local/bin/gfortran -o f ' file_name '.f90;', + ...
                ' ./f']);
            post = sprintf('\n%s\n%s\n%s', ...
                "</pre>", ...
                "</body>", ...
                "</html>");
            
            % save timestamp for console window
            timestamp = sprintf('%s%s%s', ...
                "<time>", string(datetime('now')), "</time>");
            
            % write the last output to history for console
            app.output_history = [app.output_history sprintf('\n%s%s%s', ...
                output), timestamp, "<br>"];
            
            % Write pre, output, and post to /tools/output#.html
            fprintf(fID2, '%s', [pre app.output_history post]);
            fclose(fID2);
            
            % Export output#.html to OutputPane HTML object
            app.OutputPane.HTMLSource = fullfile(pwd, file_name_out);
            
            % Remove 3 different output files:
            %  - tools/output[1-i_index].html, which is the unused output
            %  - test/[program name].f90, with the current program name
            %  - test/f.unix, exe file from compiling f90
            if isfile(['tools/output' num2str(app.i_index) '.html'])
                 system(['cd ~/Documents/GitHub/Fossil/tools; ', + ...
                'rm output' num2str(app.i_index) '.html']);
            end
            if isfile(['test/' file_name '.f90'])
                system(['cd ~/Documents/GitHub/Fossil/test; ', + ...
                'rm ' file_name '.f90']);
            end
            if isfile('test/f')
                system(['cd ~/Documents/GitHub/Fossil/test; ', + ...
                'rm f']);
            end
            
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            
            %% --- ABOUT MENU creation/selection --- %%
            % open the about menu information: text, title, and image
            text = {'Programming IDE for antiquated languages,',...
                [' Current version = ' app.ver],...
                [' Released on ' app.release_date],...
                ' See the repository at https://github.com/nrwade0/Fossil'};
            title = "About Fossil IDE";
            [imgdata, imgcmap] = imread('res/car.jpg');
            
            % Change options to TeX interpreter, modal dialog box
            options.Interpreter = 'tex';
            options.WindowStyle = 'modal';
            
            % Create box and reposition in the middle
            m = msgbox(text, title, "custom", imgdata, imgcmap, options);
            m.Position = [(app.ss(3)-330)/2 (app.ss(4)-90)/2 330 90];
            
        end

        % Button pushed function: CHECKSYNTAXButton
        function CHECKSYNTAXButtonPushed(app, event)
            
            %% --- Showing Syntax colors --- %%
            % Choose the current file to be working with:
            %  MATLAB doesn't refresh the uihtml object if you try to
            %  reopen a file with the same name. Therefore, a public
            %  property called 'app.file_swap_i' is declared that changes
            %  between 0 and 1. This is appended after the file name.
            file_name = ['tools/index' num2str(app.i_index) '.html'];
            app.i_index = 1 - app.i_index;
            
            % Remove other index#.html
            if isfile(['tools/index' num2str(app.i_index) '.html'])
                 system(['cd ~/Documents/GitHub/Fossil/tools; ', + ...
                'rm index' num2str(app.i_index) '.html']);
            end
            
            % Open tools/index#.html which is referenced to fortran.css & 
            %  fortran.js and write the preamble, code, and post script
            %  from the editor to the html file at CHECKSYNTAX button push.
            %  It outputs into the HTML window bearing Fortran syntax.
            fileID = fopen(file_name,'w+');
            pre = sprintf('%s\n  %s\n    %s\n      %s\n', ...
                "<pre class = ""line-numbers"">", ...
                "<link href=""fortran.css"" rel=""stylesheet"" />", ...
                "<script src=""fortran.js""></script>", ...
                "<code class=""language-fortran"" style=""font-size:12px;"">");
            code = get(app.EditorPane, 'Value');
            post = sprintf('  %s\n%s', '</code>', '</pre>');
            
            % Write to HTML object, close file, and load index#.html into
            %  syntax pane with fortran.css/.js style for Fortran code
            fprintf(fileID, '%s', [pre sprintf('%s\n', code{:}) post]);
            fclose(fileID);
            app.SyntaxPane.HTMLSource = fullfile(pwd, file_name);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FossilUI = uifigure('Visible', 'off');
            app.FossilUI.Name = 'Fossil 0.9';
            app.FileMenu = uimenu(app.FossilUI);
            app.FileMenu.Text = 'File';
            app.AboutMenu = uimenu(app.FileMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.ProgramInfoMenu = uimenu(app.FileMenu);
            app.ProgramInfoMenu.Text = 'Program Info';
            app.EditMenu = uimenu(app.FossilUI);
            app.EditMenu.Text = 'Edit';
            app.LeftPanel = uipanel(app.FossilUI);
            app.EditorPane = uitextarea(app.LeftPanel);
            app.RightPanel = uipanel(app.FossilUI);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.OutputPane = uihtml(app.OutputTab);
            app.RUNButton = uibutton(app.OutputTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.ProgramNamef90Label = uilabel(app.OutputTab);
            app.ProgramNamef90Label.Text = 'Program Name (.f90)';
            app.ProgramName = uieditfield(app.OutputTab, 'text');
            app.SyntaxTab = uitab(app.TabGroup);
            app.SyntaxTab.Title = 'Syntax';
            app.SyntaxPane = uihtml(app.SyntaxTab);
            app.CHECKSYNTAXButton = uibutton(app.SyntaxTab, 'push');
            app.CHECKSYNTAXButton.ButtonPushedFcn = createCallbackFcn(app, @CHECKSYNTAXButtonPushed, true);
            app.CHECKSYNTAXButton.Text = 'CHECK SYNTAX';
            % Show the figure after all components are created
            app.FossilUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FossilUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FossilUI)
        end
    end
end